classdef DBProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBProcessorUIFigure           matlab.ui.Figure
        MATLABDATAPROCESSINGSYSTEMLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        INPUTTab                      matlab.ui.container.Tab
        RecordyourpointsfromthefirsttothelastLabel  matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
        TypeparameterXEditFieldLabel  matlab.ui.control.Label
        inputx                        matlab.ui.control.EditField
        TypeparameterYLabel           matlab.ui.control.Label
        inputy                        matlab.ui.control.EditField
        TypepointnameLabel            matlab.ui.control.Label
        point_name                    matlab.ui.control.EditField
        OUTPUTTab                     matlab.ui.container.Tab
        coord_table                   matlab.ui.control.Table
        ProcesseddataanditsresultsLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
    end


    methods (Access = public)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                      conn = database('MysqlConnectionDataSource','root','root');
             colnames = {'inputx', 'inputy', 'pointname' };
             tablename = 'coordinates';
      
            selectquery = 'SELECT inputx,inputy,pointname FROM coordinates';


%              data = select(conn,selectquery);
              data = fetch(conn,selectquery);

%               app.coord_table.Data = {8 2 3; 4 5 6};
             
             disp(data);
             
  uit = uitable(app.OUTPUTTab,'ColumnName',colnames,'Data',data);
  uit.FontSize = 14;
  uit.Position =[22 24 708 138];
  uit.BackgroundColor = [1 1 .9; .9 .95 1;1 .5 .5];
             
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

            
            %conn = Connector;
            % Get textbox input  
          valuex = app.inputx.Value;
          valuey = app.inputy.Value; 
          valuepointname = app.point_name.Value;         
             
              %Textbox validation
               if(isempty(valuex))
                f=  errordlg('X, Text box can not be empty','Error');
             elseif (isempty(valuey))
                f = errordlg('Y, Text box can not be empty','Error');
             elseif (isempty(valuepointname))
                f = errordlg('Point name, Text box can not be empty','Error');
                
           elseif (isempty(str2num(valuex)) || isempty(str2num(valuey)))
                  f=  errordlg('X and Y, intputs must be numeric','Error');
               else
                   
             %collecting form data input      
                   
             %Write database 
              
               tablename = 'coordinates';
               mycolnames = {'inputx', 'inputy', 'pointname' };
               data = {valuex, valuey, valuepointname}; 
               
               conn = database('MysqlConnectionDataSource','root','root');
             
              %Here you insert the data. 
                try 
               insert(conn, tablename, mycolnames, data);      
               errordlg('Point Successfuly saved', 'Success'); 
                catch
              errordlg('Something went wrong ', 'Error');                   
                end
             
                %Clear form input 
               app.inputx.Value = '';
               app.inputy.Value = '';
               app.point_name.Value = '';
               
               end


        end

        % Callback function
        function SubmitButtonPushed2(app, event)
            
        end

        % Callback function
        function inputxValueChanged(app, event)
            valuex = app.inputx.Value;
        end

        % Callback function
        function inputyValueChanged(app, event)
            valuey = app.inputy.Value;
          
        end

        % Callback function
        function OUTPUTTabSizeChanged(app, event)
            position = app.OUTPUTTab.Position;
            
        end

        % Cell edit callback: coord_table
        function coord_tableCellEdit(app, event)
            
        end

        % Cell selection callback: coord_table
        function coord_tableCellSelection(app, event)
   
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
              
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBProcessorUIFigure = uifigure;
            app.DBProcessorUIFigure.Name = 'DBProcessor';
            app.MATLABDATAPROCESSINGSYSTEMLabel = uilabel(app.DBProcessorUIFigure);
            app.MATLABDATAPROCESSINGSYSTEMLabel.Text = 'MATLAB DATA PROCESSING SYSTEM';
            app.TabGroup = uitabgroup(app.DBProcessorUIFigure);
            app.INPUTTab = uitab(app.TabGroup);
            app.INPUTTab.Title = 'INPUT';
            app.RecordyourpointsfromthefirsttothelastLabel = uilabel(app.INPUTTab);
            app.RecordyourpointsfromthefirsttothelastLabel.Text = 'Record your points from the first to the last';
            app.SubmitButton = uibutton(app.INPUTTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.TypeparameterXEditFieldLabel = uilabel(app.INPUTTab);
            app.TypeparameterXEditFieldLabel.Text = 'Type parameter X';
            app.inputx = uieditfield(app.INPUTTab, 'text');
            app.TypeparameterYLabel = uilabel(app.INPUTTab);
            app.TypeparameterYLabel.Text = 'Type parameter Y';
            app.inputy = uieditfield(app.INPUTTab, 'text');
            app.TypepointnameLabel = uilabel(app.INPUTTab);
            app.TypepointnameLabel.Text = '    Type point name';
            app.point_name = uieditfield(app.INPUTTab, 'text');
            app.OUTPUTTab = uitab(app.TabGroup);
            app.OUTPUTTab.Title = 'OUTPUT';
            app.coord_table = uitable(app.OUTPUTTab);
            app.coord_table.ColumnName = {'input x'; 'input y'; 'point name'};
            app.coord_table.RowName = {};
            app.coord_table.CellEditCallback = createCallbackFcn(app, @coord_tableCellEdit, true);
            app.coord_table.CellSelectionCallback = createCallbackFcn(app, @coord_tableCellSelection, true);
            app.ProcesseddataanditsresultsLabel = uilabel(app.OUTPUTTab);
            app.ProcesseddataanditsresultsLabel.Text = 'Processed data and its results';
            app.CalculateButton = uibutton(app.OUTPUTTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PlotButton = uibutton(app.OUTPUTTab, 'push');
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DBProcessor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBProcessorUIFigure)
        end
    end
end